classdef final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghiemTab                     matlab.ui.container.Tab
        TNNhapphuongtrinhlap          matlab.ui.control.EditField
        NhpphngtrnhlpLabel            matlab.ui.control.Label
        TNSolanlap                    matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        TNKetqua                      matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        TNMethods                     matlab.ui.control.DropDown
        PhngphptmnghimLabel           matlab.ui.control.Label
        TNSaiso                       matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel         matlab.ui.control.Label
        TNNhapB                       matlab.ui.control.NumericEditField
        bLabel                        matlab.ui.control.Label
        TNNhapA                       matlab.ui.control.NumericEditField
        aLabel                        matlab.ui.control.Label
        TNNhapphuongtrinh             matlab.ui.control.EditField
        NhphphngtrnhLabel             matlab.ui.control.Label
        TNButton                      matlab.ui.control.Button
        TNNhapkhoangphanli            matlab.ui.control.Label
        TNDothi                       matlab.ui.control.UIAxes
        NoisuyTab                     matlab.ui.container.Tab
        NSButton                      matlab.ui.control.Button
        NSKetqua                      matlab.ui.control.NumericEditField
        KtqunisuyLabel                matlab.ui.control.Label
        NSGiatri                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel           matlab.ui.control.Label
        NSDathuc                      matlab.ui.control.EditField
        KtquathcnisuyLabel            matlab.ui.control.Label
        NSPP                          matlab.ui.control.DropDown
        PhngphpnisuyDropDownLabel     matlab.ui.control.Label
        NSNhapY                       matlab.ui.control.EditField
        NhpYLabel                     matlab.ui.control.Label
        NSNhapX                       matlab.ui.control.EditField
        NhpxLabel_2                   matlab.ui.control.Label
        NSUIAxes                      matlab.ui.control.UIAxes
        HoiquyTab                     matlab.ui.container.Tab
        HQPredict                     matlab.ui.control.Label
        HQPredictLabel                matlab.ui.control.Label
        HQPhuongtrinh                 matlab.ui.control.Label
        HQPhuongtrinhLabel            matlab.ui.control.Label
        HQMethod                      matlab.ui.control.DropDown
        HQCalcButton                  matlab.ui.control.Button
        HQDuDoan                      matlab.ui.control.NumericEditField
        NhpgitrdonLabel               matlab.ui.control.Label
        HQMethodLabel                 matlab.ui.control.Label
        HQNhapY                       matlab.ui.control.EditField
        NhpyLabel                     matlab.ui.control.Label
        HQNhapX                       matlab.ui.control.EditField
        NhpxLabel                     matlab.ui.control.Label
        HQDoThi                       matlab.ui.control.UIAxes
        DaohamTab                     matlab.ui.container.Tab
        DHTitle                       matlab.ui.control.Label
        DHResult                      matlab.ui.control.Label
        DHLamp                        matlab.ui.control.Lamp
        DHSwitch                      matlab.ui.control.Switch
        TimaohamquahamsSwitchLabel    matlab.ui.control.Label
        DHInputFx                     matlab.ui.control.EditField
        NhpvaohamsfxLabel             matlab.ui.control.Label
        DHInputY                      matlab.ui.control.EditField
        NhpvaodliuyEditFieldLabel     matlab.ui.control.Label
        DHInputX                      matlab.ui.control.EditField
        NhpvaodliuxEditFieldLabel     matlab.ui.control.Label
        DHSaiSoOptions                matlab.ui.control.DropDown
        ChongiatrisaisOhhocOh2DropDownLabel  matlab.ui.control.Label
        DHCalcButton                  matlab.ui.control.Button
        DHCalculateValue              matlab.ui.control.NumericEditField
        NhpgiatricntinhaohamEditFieldLabel  matlab.ui.control.Label
        DHDeriveMethodsOption         matlab.ui.control.DropDown
        ChonphngphapaohamXpxitrnluitrungtmDropDownLabel  matlab.ui.control.Label
        NhpvaodliuyLabel_2            matlab.ui.control.Label
        DHInputStep                   matlab.ui.control.NumericEditField
        TichphanTab                   matlab.ui.container.Tab
        TPLamp                        matlab.ui.control.Lamp
        LampLabel                     matlab.ui.control.Label
        TPKetqua                      matlab.ui.control.Label
        TPNhapcanduoi                 matlab.ui.control.NumericEditField
        NhpcndiLabel                  matlab.ui.control.Label
        TPNhapN                       matlab.ui.control.NumericEditField
        NhpNLabel                     matlab.ui.control.Label
        TPOnOff                       matlab.ui.control.Switch
        TimaohamquahamsSwitchLabel_2  matlab.ui.control.Label
        TPNhaphamso                   matlab.ui.control.EditField
        NhpvaohamsfxLabel_2           matlab.ui.control.Label
        TPNhapY                       matlab.ui.control.EditField
        NhpvaodliuyEditFieldLabel_2   matlab.ui.control.Label
        TPNhapX                       matlab.ui.control.EditField
        NhpvaodliuxEditFieldLabel_2   matlab.ui.control.Label
        TPNhapcantren                 matlab.ui.control.NumericEditField
        NhpcntrnLabel                 matlab.ui.control.Label
        TPChonPP                      matlab.ui.control.DropDown
        ChonphngphapTchphnHnhthangSimpson13Simpson38Nchiahtcho3Label  matlab.ui.control.Label
        TPCalcButton                  matlab.ui.control.Button
        GioiThieuNhomTab              matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        NhmCamelCupLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        DerivativeObject % Description    
        TichphanObject % Description
        HoiquyObject % Description
        NoiSuyObject
        TimNghiemObject
    end
    
    methods (Access = private)

        function newstr = appendDot(app, source)
            newstr = strrep(source, '^', '.^');
            newstr = strrep(newstr, '*', '.*');
            newstr = strrep(newstr, '/', './');
        end
        
        %Convert string to vector.
        function vec = str2vector(app, source)
            num = textscan(source, '%f', 'Delimiter',',');
            vec = num{1}';
        end
        
        function [valid, vec] = checkValidDHXa(app, str)

            vec = [];
            [valid, xa] = app.checkValidVector(str);

            if ~valid
                return;
            end

            hVec = diff(xa);
            h = hVec(1);
            e = 1e-12;
            vec = xa;

            for i = 2:length(hVec)
                if abs(hVec(i) - h) < e
                     continue
                else
                    valid = 0;
                    vec = [];
                    break;
                end
            end
        end

        function [valid, vec] = checkValidVector(app, str)
            invalidChars = regexp(str,'[^0-9., ]', 'once');  

            if isempty(invalidChars)
                valid = 1;
                vec = app.str2vector(str);
            else
                valid = 0;
                vec = [];
                return;
            end
        end

        % Check if it's a valid function. If valid, return 1, else return
        % 0.
        function valid = checkValidFunc(app, funcStr)
            invalidChars = regexp(funcStr, '[^0-9\s\sin\cos\(\)\+\-\*\/\^\=x]', 'match');

            if isempty(invalidChars) && contains(funcStr, 'x')
                valid = 1;
            else 
                valid = 0;
            end
        end
        

        % Modify the visibility of DH tab.
        function modifyDHTab(app, xEnable, yEnable, lampColor, stepEnable, fxEnable)
            app.DHInputX.Enable = xEnable;
            app.DHInputY.Enable = yEnable;
            app.DHLamp.Color = lampColor;

            app.DHInputStep.Enable = stepEnable;
            app.DHInputFx.Enable = fxEnable;
        end
        
        function modifyTPTab(app, xEnable, yEnable, lampColor, fxEnable)
            app.TPNhapX.Enable = xEnable;
            app.TPNhapY.Enable = yEnable;
            app.TPLamp.Color = lampColor;

            
            app.TPNhaphamso.Enable = fxEnable;
        
        end
        %Startup function for DH tab. This function will create a
        %derivative object and disable find derivative using fx.
        function DHStartUpFcn(app)
           app.DerivativeObject = Derivative(0, 0, 0);
 
           app.DHSwitch.Value = 'Off';
           modifyDHTab(app, 'on', 'on', [0.50,0.50,0.50], 'off', 'off');
        end
        
        function TPStartUpFcn(app)
           app.TichphanObject = Tichphan(0, 0);

           app.TPOnOff.Value = 'Off';
           modifyTPTab(app, 'on', 'on', [0.50,0.50,0.50], 'off');
        end
        
        function danhSachThanhVienStarUpFcn(app)
            danhSach = readtable("DSTV.xlsx","Sheet",1);
            app.UITable.Data = danhSach;
        end
    
        
        function HQStartUpFcn(app)
            app.HoiquyObject = Hoiquy;
            
        end

        function NSStartUpFcn(app)
            app.NoiSuyObject= NoiSuy;
        end
        function TimNghiemStartUpFcn(app)
            app.TimNghiemObject = TimNghiem;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DHStartUpFcn(app);
            danhSachThanhVienStarUpFcn(app);
            HQStartUpFcn(app);
            TPStartUpFcn(app);
            NSStartUpFcn(app);
            TimNghiemStartUpFcn(app);
        end

        % Value changed function: DHSwitch
        function DHSwitchValueChanged(app, event)
            value = app.DHSwitch.Value;

            if strcmp(value,'Off')
                app.DerivativeObject.switchState = 0;
                modifyDHTab(app, 'on', 'on', [0.50,0.50,0.50], 'off', 'off')
                app.DHInputFx.Value = '';
                app.DHInputStep.Value = 0;
            elseif strcmp(value,'On')
                app.DerivativeObject.switchState = 1;
                modifyDHTab(app, 'off', 'off', 'g', 'on', 'on');
                app.DHInputX.Value = '';
                app.DHInputY.Value = '';
            end
        end

        % Value changed function: DHInputX
        function DHInputXValueChanged(app, event)
            value = app.DHInputX.Value;
           
            [valid, vec] = app.checkValidDHXa(value);
            
            if valid 
                app.DerivativeObject.xa = vec;
            else
                msgbox(["Nhập sai dữ liệu x"; "Các phần tử của x phải cách đều nhau một khoảng h và không phải các kí tự chữ hoặc dấu!"; "Vui lòng nhập lại."], "Error",'error');
                app.DerivativeObject.xa = '';
            end        
        end

        % Value changed function: DHInputY
        function DHInputYValueChanged(app, event)
            value = app.DHInputY.Value;
            
            [valid, vec] = app.checkValidVector(value);

            if valid
                app.DerivativeObject.ya = vec;
            else
                msgbox(["Nhập sai dữ liệu y"; "Các phần tử của y không phải các kí tự chữ hoặc dấu!"; "Vui lòng nhập lại."], "Error",'error');
                app.DerivativeObject.ya = '';
            end
        end

        % Value changed function: DHInputFx
        function DHInputFxValueChanged(app, event)
            value = app.DHInputFx.Value;

            try
                syms x;
                f = str2func(['@(x)', value]);
                app.DerivativeObject.fx = f;
            catch
                msgbox(["Nhập hàm không đúng.";"Hãy nhập lại hàm không chứa các kí tự từ a-z hoặc A-Z."; "Vui lòng nhập lại."], "Error", 'error');
                app.DerivativeObject.fx = '';
            end

        end

        % Value changed function: DHInputStep
        function DHInputStepValueChanged(app, event)
            value = app.DHInputStep.Value;
            
            if value <= 0
                msgbox(["Nhập bước nhảy không đúng."; "Bước nhảy phải lớn hơn 0!"; "Vui lòng nhập lại."], "Error", 'error');
                app.DerivativeObject.h = [];
            else
                app.DerivativeObject.h = value;
            end
        end

        % Value changed function: DHSaiSoOptions
        function DHSaiSoOptionsValueChanged(app, event)
            value = app.DHSaiSoOptions.Value;
            
            if strcmp(value, 'O(h)')
                app.DerivativeObject.Oh = 0; %O(h)
            else 
                app.DerivativeObject.Oh = 1; %O(h^2)
            end
        end

        % Button pushed function: DHCalcButton
        function DHCalcButtonPushed(app, event)
            try

                if app.DerivativeObject.switchState == 0
                    xa = app.DerivativeObject.xa;
                    ya = app.DerivativeObject.ya;
                    lengthX = length(xa);
                    lengthY = length(ya);
                    x = app.DerivativeObject.xDH;
                    valid = 1;

                    if (lengthX == lengthY) && (x >= min(xa) && x <= max(xa))
                        valid = 1;
                    else
                        valid = 0;
                    end
                    if valid 
                        result = app.DerivativeObject.LagrangeDerivative();
                    else
                        msgbox(["Nhập sai dữ liệu !"; "Số phần tử của mảng x, y phải bằng nhau và giá trị dự đoán x phải nằm trong mảng x!"; "Vui lòng nhập lại."], "Error",'error');
                        return;
                    end

                else
                    result = app.DerivativeObject.fxDerivative();
                end

                if ~isempty(result)
                    app.DHResult.Text = sprintf('Kết quả: %.10f', result);
                else
                    msgbox(["Nhập thiếu hoặc sai dữ liệu."; "Vui lòng kiểm tra xem đã nhập đủ dữ liệu cho việc tính toán hay không!"],'Error', 'error');
                end

            catch
                msgbox(["Nhập thiếu hoặc sai dữ liệu."; "Vui lòng kiểm tra xem đã nhập đủ dữ liệu cho việc tính toán hay không!"],'Error', 'error');
            end
        end

        % Value changed function: DHCalculateValue
        function DHCalculateValueValueChanged(app, event)
            value = app.DHCalculateValue.Value;
            
            app.DerivativeObject.xDH = value;
        end

        % Value changed function: DHDeriveMethodsOption
        function DHDeriveMethodsOptionValueChanged(app, event)
            value = app.DHDeriveMethodsOption.Value;
            
            if strcmp(value, 'Xấp xỉ tiến')
                app.DerivativeObject.derivativeMethod = 0; %Xap xi tren
            elseif strcmp(value, 'Xấp xỉ lùi')
                app.DerivativeObject.derivativeMethod = 1; %Xap xi lui
            else
                app.DerivativeObject.derivativeMethod = 2; %Xap xi trung tam
            end
        end

        % Value changed function: HQNhapX
        function HQNhapXValueChanged(app, event)
            value = app.HQNhapX.Value;
            
            [valid, vec] = app.checkValidVector(value);

            if valid
                app.HoiquyObject.xa = vec;
            else
                msgbox(["Nhập sai dữ liệu x"; "Các phần tử của x không phải các kí tự chữ hoặc dấu!"; "Vui lòng nhập lại."], "Error",'error');
            end
        end

        % Value changed function: HQNhapY
        function HQNhapYValueChanged(app, event)
            value = app.HQNhapY.Value;
            
            [valid, vec] = app.checkValidVector(value);

            if valid
                app.HoiquyObject.ya = vec;
            else
                msgbox(["Nhập sai dữ liệu y"; "Các phần tử của y không phải các kí tự chữ hoặc dấu!"; "Vui lòng nhập lại."], "Error",'error');
            end
        end

        % Value changed function: HQDuDoan
        function HQDuDoanValueChanged(app, event)
            app.HoiquyObject.x = app.HQDuDoan.Value;
        end

        % Callback function: HQCalcButton, HQMethod
        function HQCalcButtonPushed(app, event)
            na = length(app.HoiquyObject.xa);
            nb = length(app.HoiquyObject.ya);
            checkx = ismember(app.HoiquyObject.x,app.HoiquyObject.xa);
            pp = app.HQMethod.Value;
            if na == nb
                if checkx == 1
                    if strcmp(pp,'Hồi quy tuyến tính') == 0
                        y = app.HoiquyObject.hoiQuyTuyenTinh();
                        result = app.HoiquyObject.phuongTrinhHoiQuyTuyenTinh();
                    elseif strcmp(pp,'Hồi quy hàm mũ') == 0
                        y = app.HoiquyObject.hoiQuyHamMu();
                        result = app.HoiquyObject.phuongTrinhHoiQuyHamMu();
                    else
                        y = app.HoiquyObject.hoiQuyLogarit();
                        result = app.HoiquyObject.phuongTrinhHoiQuyLogarit();
                    end
                    app.HQPredict.Text = num2str(y);
                    app.HQPhuongtrinh.Text = string(result);
                    plot(app.HQDoThi,app.HoiquyObject.xa,app.HoiquyObject.ya,'o')
                else
                    msgbox(["Nhập sai dữ liệu !"; "Giá trị dự đoán x phải nằm trong mảng x!"; "Vui lòng nhập lại."], "Error",'error');
                end
            else
                msgbox(["Nhập sai dữ liệu !"; "Số phần tử của mảng x, y phải bằng nhau!"; "Vui lòng nhập lại."], "Error",'error');
            end
        end

        % Value changed function: TPNhapX
        function TPNhapXValueChanged(app, event)
            app.TichphanObject.xa = str2num(app.TPNhapX.Value);
            value = app.TPNhapX.Value;
            
            [valid, vec] = app.checkValidVector(value);

            if valid
                app.TichphanObject.xa = vec;
            else
                msgbox(["Nhập sai dữ liệu x"; "Các phần tử của y không phải các kí tự chữ hoặc dấu!"; "Vui lòng nhập lại."], "Error",'error');
            end
        end

        % Value changed function: TPNhapY
        function TPNhapYValueChanged(app, event)
            app.TichphanObject.ya = str2num(app.TPNhapX.Value);
            value = app.TPNhapY.Value;
            
            [valid, vec] = app.checkValidVector(value);

            if valid
                app.TichphanObject.ya = vec;
            else
                msgbox(["Nhập sai dữ liệu "; "Các phần tử của y không phải các kí tự chữ hoặc dấu!"; "Vui lòng nhập lại."], "Error",'error');
            end
        end

        % Value changed function: TPNhaphamso
        function TPNhaphamsoValueChanged(app, event)
            value = app.TPNhaphamso.Value;
            
            try
                    syms x;
                    f = str2func(['@(x)', value]);
                    app.TichphanObject.fx = f; 
            catch
                msgbox(["Nhập hàm không đúng.";"Hãy kiểm tra xem có thiếu các dấu ^, *, /, ngoặc hay không."; "Vui lòng nhập lại."], "Error", 'error');
                app.TichphanObject.fx = '';
            end
        end

        % Value changed function: TPNhapcantren
        function TPNhapcantrenValueChanged(app, event)
           app.TichphanObject.canTren = app.TPNhapcantren.Value;  
            
        end

        % Value changed function: TPNhapcanduoi
        function TPNhapcanduoiValueChanged(app, event)
           app.TichphanObject.canDuoi = app.TPNhapcanduoi.Value;
            
        end

        % Value changed function: TPNhapN
        function TPNhapNValueChanged(app, event)
            app.TichphanObject.N = app.TPNhapN.Value;
        end

        % Button pushed function: TPCalcButton
        function TPCalcButtonPushed(app, event)
          gt = rem(app.TichphanObject.N,3);
          gtma = length(app.TichphanObject.xa);
          gtmb = length(app.TichphanObject.ya);
          if ((app.TichphanObject.K == 0) && (gtma ~= gtmb)) 
            msgbox(["Mảng x và y phải có kích thước bằng nhau !!!";"Vui lòng nhập lại."], "Error", 'error');
          else
            if app.TichphanObject.K == 0
                    result = app.TichphanObject.XYTichphan();           
            else
                    result = app.TichphanObject.fxTichphan();
            end
            if ((app.TichphanObject.PP == 2) && (gt ~= 0))
              msgbox(["Phương pháp Simpson 3/8 thì N phải chia hết cho 3 !!!";"Vui lòng nhập lại."], "Error", 'error');
            else        
              app.TPKetqua.Text = sprintf('Kết quả: %.10f', result);
            end
          end
          if (app.TichphanObject.K == 1) 
            if app.TichphanObject.K == 0
                    result = app.TichphanObject.XYTichphan();           
            else
                    result = app.TichphanObject.fxTichphan();
            end
            if ((app.TichphanObject.PP == 2) && (gt ~= 0))
              msgbox(["Phương pháp Simpson 3/8 thì N phải chia hết cho 3 !!!";"Vui lòng nhập lại."], "Error", 'error');
            else        
              app.TPKetqua.Text = sprintf('Kết quả: %.10f', result);
            end
          end
        end

        % Value changed function: TPOnOff
        function TPOnOffValueChanged(app, event)
            value = app.TPOnOff.Value;
            if strcmp(value,'Off')
                app.TichphanObject.K = 0;
                modifyTPTab(app, 'on', 'on', [0.50,0.50,0.50], 'off');
                app.TPNhaphamso.Value = '';
            elseif strcmp(value,'On')
                app.TichphanObject.K = 1;
                modifyTPTab(app, 'off', 'off', 'g', 'on');
                app.TPNhapX.Value = '';
                app.TPNhapY.Value = '';
            end
            
        end

        % Value changed function: TPChonPP
        function TPChonPPValueChanged(app, event)
            value = app.TPChonPP.Value;
            
            if strcmp(value, 'Hình thang')
                app.TichphanObject.PP= 0;  %tich phan hinh thang
            elseif strcmp(value, 'Simpson 1/3')
                app.TichphanObject.PP= 1;  %simpson 1/3
            else
                app.TichphanObject.PP = 2; %simpson 3/8
            end
            
        end

        % Value changed function: NSNhapX
        function NSNhapXValueChanged(app, event)
            value = app.NSNhapX.Value;
            [valid, vec] = app.checkValidVector(value);
            if valid 
                app.NoiSuyObject.xa = vec;
            else
                msgbox(["Nhập sai dữ liệu x"; "Các phần tử của x không chứa các kí tự chữ hoặc dấu"; "Vui lòng nhập lại."], "Error",'error');
            end 
        end

        % Value changed function: NSNhapY
        function NSNhapYValueChanged(app, event)
            value = app.NSNhapY.Value;
            app.NoiSuyObject.ya = str2num(value); 
            [valid, vec] = app.checkValidVector(value);
            if valid
                app.NoiSuyObject.ya = vec;
            else
                msgbox(["Nhập sai dữ liệu y"; "Các phần tử của y không chứa các kí tự chữ hoặc dấu"; "Vui lòng nhập lại."], "Error",'error');
            end
        end

        % Value changed function: NSGiatri
        function NSGiatriValueChanged(app, event)
            value = app.NSGiatri.Value;
            %if value >= min(app.NoiSuyObject.xa) && value <= max(app.NoiSuyObject.xa)
            app.NoiSuyObject.x = value;
            %else
                %msgbox(["Giá trị cần tính nội suy cần nằm trong khoảng giá trị của mảng xa"; "Vui lòng nhập lại."], "Error",'error');
            %end
        end

        % Value changed function: NSPP
        function NSPPValueChanged(app, event)
            value = app.NSPP.Value;
            
            if strcmp(value, 'Newton')
                app.NoiSuyObject.ppNoiSuy = 0; %Newton
            else
                app.NoiSuyObject.ppNoiSuy = 1; %Lagrange
            end
        end

        % Value changed function: TNNhapphuongtrinh
        function TNNhapphuongtrinhValueChanged(app, event)
            pt = app.TNNhapphuongtrinh.Value;

            try
                pt = app.appendDot(pt);
                syms x;
                f = str2func(['@(x)', pt]);
                app.TimNghiemObject.f = f;
            catch
                msgbox(["Nhập hàm không đúng.";"Hãy kiểm tra xem có thiếu các dấu ^, *, /, ngoặc hay không."; "Vui lòng nhập lại."], "Error", 'error');
                app.TimNghiemObject.f = '';
            end
        end

        % Value changed function: TNNhapphuongtrinhlap
        function TNNhapphuongtrinhlapValueChanged(app, event)
            ptlap = app.TNNhapphuongtrinhlap.Value;
            try
                ptlap = app.appendDot(ptlap);
                syms x;
                fp = str2func(['@(x)', ptlap]);
                app.TimNghiemObject.fp = fp;
           catch
                msgbox(["Nhập hàm không đúng.";"Hãy kiểm tra xem có thiếu các dấu ^, *, /, ngoặc hay không."; "Vui lòng nhập lại."], "Error", 'error');
                app.TimNghiemObject.fp = '';
            end
        end

        % Value changed function: TNNhapA
        function TNNhapAValueChanged(app, event)
            app.TimNghiemObject.a = app.TNNhapA.Value;
        end

        % Value changed function: TNNhapB
        function TNNhapBValueChanged(app, event)
            app.TimNghiemObject.b = app.TNNhapB.Value;
        end

        % Value changed function: TNSaiso
        function TNSaisoValueChanged(app, event)
            app.TimNghiemObject.saiso = app.TNSaiso.Value;
        end

        % Callback function: TNButton, TNMethods
        function TNButtonPushed(app, event)
            pp = app.TNMethods.Value;
            if pp == "Chia đôi"
                [nghiem, solanlap] = app.TimNghiemObject.chiadoi();
                app.TNKetqua.Value = double(nghiem);
                app.TNSolanlap.Value = solanlap;
            elseif pp == "Lặp"
                [nghiem, solanlap] = app.TimNghiemObject.lap();
                app.TNKetqua.Value = double(nghiem);
                app.TNSolanlap.Value = solanlap;
            elseif pp == "Tiếp tuyến"
                [nghiem, solanlap] = app.TimNghiemObject.tieptuyen_new();
                app.TNKetqua.Value = double(nghiem);
                app.TNSolanlap.Value = solanlap;
            else
                [nghiem, solanlap] = app.TimNghiemObject.daycung();
                app.TNKetqua.Value = double(nghiem);
                app.TNSolanlap.Value = solanlap;
            end
            xn = linspace(app.TimNghiemObject.a, app.TimNghiemObject.b, 100);
            pt = app.TimNghiemObject.f;
            yn = pt(xn);
            ynghiem = pt(nghiem);
            plot(app.TNDothi , xn, yn, nghiem, ynghiem, 'r-o');
        end

        % Button pushed function: NSButton
        function NSButtonPushed(app, event)
            try
            x = app.NoiSuyObject.x;

            if x >= min(app.NoiSuyObject.xa) && x <= max(app.NoiSuyObject.xa)


                if app.NoiSuyObject.ppNoiSuy ==0
                    result= app.NoiSuyObject.NewtonInterpolation(x);
                polinominal = app.NoiSuyObject.NewtonPolynominal();
                else
                    result= app.NoiSuyObject.Lagrange(x);
                    polinominal = app.NoiSuyObject.LagrangePolynominal();
                end
            else
                msgbox(["Giá trị cần tính nội suy cần nằm trong khoảng giá trị của mảng xa"; "Vui lòng nhập lại."], "Error",'error');
            end
                app.NSKetqua.Value= result;
                app.NSDathuc.Value = string(polinominal);

                plot (app.NSUIAxes,app.NoiSuyObject.xa, app.NoiSuyObject.ya, 'o')
                        
                hold(app.NSUIAxes, 'on');          
                x_values = linspace(min(app.NoiSuyObject.xa), max(app.NoiSuyObject.xa));
                y_values = arrayfun(@(x) subs(polinominal, x), x_values);
                plot(app.NSUIAxes,x_values, y_values, 'r');
                hold(app.NSUIAxes, 'off');
            catch
                msgbox(["Nhập thiếu hoặc sai dữ liệu."; "Vui lòng kiểm tra lại dữ liệu đã nhập"],'Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.TNDothi = uiaxes(app.NghiemTab);
            title(app.TNDothi, 'Đồ thị hàm số')
            xlabel(app.TNDothi, 'X')
            ylabel(app.TNDothi, 'Y')
            zlabel(app.TNDothi, 'Z')
            app.TNNhapkhoangphanli = uilabel(app.NghiemTab);
            app.TNNhapkhoangphanli.Text = 'Nhập khoảng phân li nghiệm:';
            app.TNButton = uibutton(app.NghiemTab, 'push');
            app.TNButton.ButtonPushedFcn = createCallbackFcn(app, @TNButtonPushed, true);
            app.TNButton.Text = 'Tính';
            app.NhphphngtrnhLabel = uilabel(app.NghiemTab);
            app.NhphphngtrnhLabel.Text = 'Nhập phương trình:';
            app.TNNhapphuongtrinh = uieditfield(app.NghiemTab, 'text');
            app.TNNhapphuongtrinh.ValueChangedFcn = createCallbackFcn(app, @TNNhapphuongtrinhValueChanged, true);
            app.aLabel = uilabel(app.NghiemTab);
            app.aLabel.Text = 'a:';
            app.TNNhapA = uieditfield(app.NghiemTab, 'numeric');
            app.TNNhapA.ValueChangedFcn = createCallbackFcn(app, @TNNhapAValueChanged, true);
            app.bLabel = uilabel(app.NghiemTab);
            app.bLabel.Text = 'b:';
            app.TNNhapB = uieditfield(app.NghiemTab, 'numeric');
            app.TNNhapB.ValueChangedFcn = createCallbackFcn(app, @TNNhapBValueChanged, true);
            app.NhpsaisEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số:';
            app.TNSaiso = uieditfield(app.NghiemTab, 'numeric');
            app.TNSaiso.ValueChangedFcn = createCallbackFcn(app, @TNSaisoValueChanged, true);
            app.PhngphptmnghimLabel = uilabel(app.NghiemTab);
            app.PhngphptmnghimLabel.Text = 'Phương pháp tìm nghiệm:';
            app.TNMethods = uidropdown(app.NghiemTab);
            app.TNMethods.Items = {'Chia đôi', 'Lặp', 'TIếp tuyến', 'Dây cung'};
            app.TNMethods.ValueChangedFcn = createCallbackFcn(app, @TNButtonPushed, true);
            app.TNMethods.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NghiemTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.TNKetqua = uieditfield(app.NghiemTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp:';
            app.TNSolanlap = uieditfield(app.NghiemTab, 'numeric');
            app.NhpphngtrnhlpLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhlpLabel.Text = 'Nhập phương trình lặp:';
            app.TNNhapphuongtrinhlap = uieditfield(app.NghiemTab, 'text');
            app.TNNhapphuongtrinhlap.ValueChangedFcn = createCallbackFcn(app, @TNNhapphuongtrinhlapValueChanged, true);
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội suy';
            app.NSUIAxes = uiaxes(app.NoisuyTab);
            title(app.NSUIAxes, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.NSUIAxes, 'X')
            ylabel(app.NSUIAxes, 'Y')
            zlabel(app.NSUIAxes, 'Z')
            app.NhpxLabel_2 = uilabel(app.NoisuyTab);
            app.NhpxLabel_2.Text = 'Nhập x';
            app.NSNhapX = uieditfield(app.NoisuyTab, 'text');
            app.NSNhapX.ValueChangedFcn = createCallbackFcn(app, @NSNhapXValueChanged, true);
            app.NhpYLabel = uilabel(app.NoisuyTab);
            app.NhpYLabel.Text = 'Nhập y';
            app.NSNhapY = uieditfield(app.NoisuyTab, 'text');
            app.NSNhapY.ValueChangedFcn = createCallbackFcn(app, @NSNhapYValueChanged, true);
            app.PhngphpnisuyDropDownLabel = uilabel(app.NoisuyTab);
            app.PhngphpnisuyDropDownLabel.Text = 'Phương pháp  nội suy';
            app.NSPP = uidropdown(app.NoisuyTab);
            app.NSPP.Items = {'Newton', 'Larange'};
            app.NSPP.ValueChangedFcn = createCallbackFcn(app, @NSPPValueChanged, true);
            app.NSPP.Value = 'Newton';
            app.KtquathcnisuyLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.NSDathuc = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.NSGiatri = uieditfield(app.NoisuyTab, 'numeric');
            app.NSGiatri.ValueChangedFcn = createCallbackFcn(app, @NSGiatriValueChanged, true);
            app.KtqunisuyLabel = uilabel(app.NoisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy';
            app.NSKetqua = uieditfield(app.NoisuyTab, 'numeric');
            app.NSButton = uibutton(app.NoisuyTab, 'push');
            app.NSButton.ButtonPushedFcn = createCallbackFcn(app, @NSButtonPushed, true);
            app.NSButton.Text = 'Tính';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi quy';
            app.HQDoThi = uiaxes(app.HoiquyTab);
            title(app.HQDoThi, 'Đồ thị hồi quy')
            xlabel(app.HQDoThi, 'X')
            ylabel(app.HQDoThi, 'Y')
            zlabel(app.HQDoThi, 'Z')
            app.NhpxLabel = uilabel(app.HoiquyTab);
            app.NhpxLabel.Text = 'Nhập mảng x';
            app.HQNhapX = uieditfield(app.HoiquyTab, 'text');
            app.HQNhapX.ValueChangedFcn = createCallbackFcn(app, @HQNhapXValueChanged, true);
            app.NhpyLabel = uilabel(app.HoiquyTab);
            app.NhpyLabel.Text = 'Nhập mảng y';
            app.HQNhapY = uieditfield(app.HoiquyTab, 'text');
            app.HQNhapY.ValueChangedFcn = createCallbackFcn(app, @HQNhapYValueChanged, true);
            app.HQMethodLabel = uilabel(app.HoiquyTab);
            app.HQMethodLabel.Text = 'Chọn phương pháp hồi quy';
            app.NhpgitrdonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrdonLabel.Text = 'Nhập giá trị dự đoán  x';
            app.HQDuDoan = uieditfield(app.HoiquyTab, 'numeric');
            app.HQDuDoan.ValueChangedFcn = createCallbackFcn(app, @HQDuDoanValueChanged, true);
            app.HQCalcButton = uibutton(app.HoiquyTab, 'push');
            app.HQCalcButton.ButtonPushedFcn = createCallbackFcn(app, @HQCalcButtonPushed, true);
            app.HQCalcButton.Text = 'Tính';
            app.HQMethod = uidropdown(app.HoiquyTab);
            app.HQMethod.Items = {'Hồi quy tuyến tính', 'Hồi quy hàm mũ', 'Hồi quy hàm logarit'};
            app.HQMethod.ValueChangedFcn = createCallbackFcn(app, @HQCalcButtonPushed, true);
            app.HQMethod.Value = 'Hồi quy tuyến tính';
            app.HQPhuongtrinhLabel = uilabel(app.HoiquyTab);
            app.HQPhuongtrinhLabel.Text = 'Phương trình hồi quy';
            app.HQPhuongtrinh = uilabel(app.HoiquyTab);
            app.HQPhuongtrinh.Text = 'nothing here';
            app.HQPredictLabel = uilabel(app.HoiquyTab);
            app.HQPredictLabel.Text = 'Kết quả dự đoán';
            app.HQPredict = uilabel(app.HoiquyTab);
            app.HQPredict.Text = '00';
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo hàm';
            app.DHInputStep = uieditfield(app.DaohamTab, 'numeric');
            app.DHInputStep.ValueChangedFcn = createCallbackFcn(app, @DHInputStepValueChanged, true);
            app.NhpvaodliuyLabel_2 = uilabel(app.DaohamTab);
            app.NhpvaodliuyLabel_2.Text = 'Nhập vào bước h';
            app.ChonphngphapaohamXpxitrnluitrungtmDropDownLabel = uilabel(app.DaohamTab);
            app.ChonphngphapaohamXpxitrnluitrungtmDropDownLabel.Text = {'Chọn phương pháp đạo hàm: Xấp xỉ trên,'; 'lùi, trung tâm'};
            app.DHDeriveMethodsOption = uidropdown(app.DaohamTab);
            app.DHDeriveMethodsOption.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DHDeriveMethodsOption.ValueChangedFcn = createCallbackFcn(app, @DHDeriveMethodsOptionValueChanged, true);
            app.DHDeriveMethodsOption.Value = 'Xấp xỉ tiến';
            app.NhpgiatricntinhaohamEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgiatricntinhaohamEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.DHCalculateValue = uieditfield(app.DaohamTab, 'numeric');
            app.DHCalculateValue.ValueChangedFcn = createCallbackFcn(app, @DHCalculateValueValueChanged, true);
            app.DHCalcButton = uibutton(app.DaohamTab, 'push');
            app.DHCalcButton.ButtonPushedFcn = createCallbackFcn(app, @DHCalcButtonPushed, true);
            app.DHCalcButton.Text = 'Tính';
            app.ChongiatrisaisOhhocOh2DropDownLabel = uilabel(app.DaohamTab);
            app.ChongiatrisaisOhhocOh2DropDownLabel.Text = {'Chọn giá trị sai số O(h) '; 'hoặc O(h^2) '};
            app.DHSaiSoOptions = uidropdown(app.DaohamTab);
            app.DHSaiSoOptions.Items = {'O(h)', 'O(h^2)'};
            app.DHSaiSoOptions.ValueChangedFcn = createCallbackFcn(app, @DHSaiSoOptionsValueChanged, true);
            app.DHSaiSoOptions.Value = 'O(h)';
            app.NhpvaodliuxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpvaodliuxEditFieldLabel.Text = 'Nhập vào dữ liệu x';
            app.DHInputX = uieditfield(app.DaohamTab, 'text');
            app.DHInputX.ValueChangedFcn = createCallbackFcn(app, @DHInputXValueChanged, true);
            app.NhpvaodliuyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpvaodliuyEditFieldLabel.Text = 'Nhập vào dữ liệu y';
            app.DHInputY = uieditfield(app.DaohamTab, 'text');
            app.DHInputY.ValueChangedFcn = createCallbackFcn(app, @DHInputYValueChanged, true);
            app.NhpvaohamsfxLabel = uilabel(app.DaohamTab);
            app.NhpvaohamsfxLabel.Text = 'Nhập vào hàm số f(x)';
            app.DHInputFx = uieditfield(app.DaohamTab, 'text');
            app.DHInputFx.ValueChangedFcn = createCallbackFcn(app, @DHInputFxValueChanged, true);
            app.TimaohamquahamsSwitchLabel = uilabel(app.DaohamTab);
            app.TimaohamquahamsSwitchLabel.Text = 'Tìm đạo hàm qua hàm số';
            app.DHSwitch = uiswitch(app.DaohamTab, 'slider');
            app.DHSwitch.ValueChangedFcn = createCallbackFcn(app, @DHSwitchValueChanged, true);
            app.DHLamp = uilamp(app.DaohamTab);
            app.DHResult = uilabel(app.DaohamTab);
            app.DHResult.Text = 'Kết quả: ';
            app.DHTitle = uilabel(app.DaohamTab);
            app.DHTitle.Text = 'Giao diện tính đạo hàm';
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.TPCalcButton = uibutton(app.TichphanTab, 'push');
            app.TPCalcButton.ButtonPushedFcn = createCallbackFcn(app, @TPCalcButtonPushed, true);
            app.TPCalcButton.Text = 'Tính';
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Nchiahtcho3Label = uilabel(app.TichphanTab);
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Nchiahtcho3Label.Text = {'Chọn phương pháp Tích phân: Hình thang;'; 'Simpson 1/3 ; Simpson 3/8 (N chia hết cho 3)'};
            app.TPChonPP = uidropdown(app.TichphanTab);
            app.TPChonPP.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8 (N chia hết cho 3)'};
            app.TPChonPP.ValueChangedFcn = createCallbackFcn(app, @TPChonPPValueChanged, true);
            app.TPChonPP.Value = 'Simpson 1/3';
            app.NhpcntrnLabel = uilabel(app.TichphanTab);
            app.NhpcntrnLabel.Text = 'Nhập cận trên ';
            app.TPNhapcantren = uieditfield(app.TichphanTab, 'numeric');
            app.TPNhapcantren.ValueChangedFcn = createCallbackFcn(app, @TPNhapcantrenValueChanged, true);
            app.NhpvaodliuxEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaodliuxEditFieldLabel_2.Text = 'Nhập vào dữ liệu x';
            app.TPNhapX = uieditfield(app.TichphanTab, 'text');
            app.TPNhapX.ValueChangedFcn = createCallbackFcn(app, @TPNhapXValueChanged, true);
            app.NhpvaodliuyEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaodliuyEditFieldLabel_2.Text = 'Nhập vào dữ liệu y';
            app.TPNhapY = uieditfield(app.TichphanTab, 'text');
            app.TPNhapY.ValueChangedFcn = createCallbackFcn(app, @TPNhapYValueChanged, true);
            app.NhpvaohamsfxLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaohamsfxLabel_2.Text = 'Nhập vào hàm số f(x)';
            app.TPNhaphamso = uieditfield(app.TichphanTab, 'text');
            app.TPNhaphamso.ValueChangedFcn = createCallbackFcn(app, @TPNhaphamsoValueChanged, true);
            app.TimaohamquahamsSwitchLabel_2 = uilabel(app.TichphanTab);
            app.TimaohamquahamsSwitchLabel_2.Text = 'Tìm đạo hàm qua hàm số';
            app.TPOnOff = uiswitch(app.TichphanTab, 'slider');
            app.TPOnOff.ValueChangedFcn = createCallbackFcn(app, @TPOnOffValueChanged, true);
            app.NhpNLabel = uilabel(app.TichphanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TPNhapN = uieditfield(app.TichphanTab, 'numeric');
            app.TPNhapN.ValueChangedFcn = createCallbackFcn(app, @TPNhapNValueChanged, true);
            app.NhpcndiLabel = uilabel(app.TichphanTab);
            app.NhpcndiLabel.Text = 'Nhập cận dưới';
            app.TPNhapcanduoi = uieditfield(app.TichphanTab, 'numeric');
            app.TPNhapcanduoi.ValueChangedFcn = createCallbackFcn(app, @TPNhapcanduoiValueChanged, true);
            app.TPKetqua = uilabel(app.TichphanTab);
            app.TPKetqua.Text = 'Kết quả:';
            app.LampLabel = uilabel(app.TichphanTab);
            app.LampLabel.Text = 'Lamp';
            app.TPLamp = uilamp(app.TichphanTab);
            app.GioiThieuNhomTab = uitab(app.TabGroup);
            app.GioiThieuNhomTab.Title = 'Giới thiệu nhóm';
            app.NhmCamelCupLabel = uilabel(app.GioiThieuNhomTab);
            app.NhmCamelCupLabel.Text = 'Nhóm CamelCup';
            app.UITable = uitable(app.GioiThieuNhomTab);
            app.UITable.ColumnName = {'STT'; 'MSSV'; 'Họ và tên'; 'Nhiệm vụ'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end